classdef subsampling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SubsampleButton  matlab.ui.control.Button
        LoadButton       matlab.ui.control.Button
        Cr11             matlab.ui.control.UIAxes
        Cr22             matlab.ui.control.UIAxes
        Cr44             matlab.ui.control.UIAxes
        Cb11             matlab.ui.control.UIAxes
        Cb22             matlab.ui.control.UIAxes
        Cb44             matlab.ui.control.UIAxes
        Output11         matlab.ui.control.UIAxes
        Output22         matlab.ui.control.UIAxes
        OGOutput         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function UpdateOGOutput(app, imageFileName)
               imageFileName=imread(imageFileName);
               imshow(imageFileName,'Parent',app.OGOutput);
               imwrite(imageFileName, 'imageFileName.png', 'png');
        end
        
        function UpdateOutput22(app, imageFileName)
            imageFileName=imread(imageFileName);
            imshow(imageFileName,'Parent',app.Output22);
        end
        
        function UpdateOutput11(app, imageFileName)
            imageFileName=imread(imageFileName);
            imshow(imageFileName,'Parent',app.Output11);
        end
        
        function UpdateCb44 (app, imageFileName)
            imageFileName=imread(imageFileName);
            imshow(imageFileName,'Parent',app.Cb44);
        end
        
        function UpdateCb22 (app, imageFileName)
          imageFileName=imread(imageFileName);
          imshow(imageFileName,'Parent',app.Cb22);  
        end
        
        function UpdateCb11 (app, imageFileName)
            imageFileName=imread(imageFileName);
            imshow(imageFileName,'Parent',app.Cb11);
        end
        
        function UpdateCr44 (app, imageFileName)
          imageFileName=imread(imageFileName);
          imshow(imageFileName,'Parent',app.Cr44); 
        end
        
        function UpdateCr22 (app, imageFileName)
            imageFileName=imread(imageFileName);
            imshow(imageFileName,'Parent',app.Cr22); 
        end
        
        function UpdateCr11 (app, imageFileName)
           imageFileName=imread(imageFileName);
           imshow(imageFileName,'Parent',app.Cr11);  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [f, p]=uigetfile({'*.jpg;*.png;*.gif','All Image Files'});

            if (ischar(p))
                imageFileName = [p f];
                UpdateOGOutput(app, imageFileName);

            end
        end

        % Button pushed function: SubsampleButton
        function SubsampleButtonPushed(app, event)

            output_44 = rgb_to_ycbcr("imageFileName.png", "4:4:4");
            UpdateOGOutput(app, output_44);

            output_22 = rgb_to_ycbcr("imageFileName.png", "4:2:2");
            UpdateOutput22(app, output_22);
            
            output_11 = rgb_to_ycbcr("imageFileName.png", "4:2:2");
            UpdateOutput11(app, output_11);

            %img_y_44 = img_ycbcr_444(:,:,1)
            
            %img_y_22 = img_ycbcr_422(:,:,1)

            %img_y_11 = img_ycbcr_411(:,:,1)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OGOutput = uiaxes(app.UIFigure);
            title(app.OGOutput, 'Original (4:4:4)')
            xlabel(app.OGOutput, 'X')
            ylabel(app.OGOutput, 'Y')
            zlabel(app.OGOutput, 'Z')
            app.Output22 = uiaxes(app.UIFigure);
            title(app.Output22, '4:2:2')
            xlabel(app.Output22, 'X')
            ylabel(app.Output22, 'Y')
            zlabel(app.Output22, 'Z')
            app.Output11 = uiaxes(app.UIFigure);
            title(app.Output11, '4:1:1')
            xlabel(app.Output11, 'X')
            ylabel(app.Output11, 'Y')
            zlabel(app.Output11, 'Z')
            app.Cb44 = uiaxes(app.UIFigure);
            title(app.Cb44, '4:4:4 (Cb)')
            xlabel(app.Cb44, 'X')
            ylabel(app.Cb44, 'Y')
            zlabel(app.Cb44, 'Z')
            app.Cb22 = uiaxes(app.UIFigure);
            title(app.Cb22, '4:2:2 (Cb)')
            xlabel(app.Cb22, 'X')
            ylabel(app.Cb22, 'Y')
            zlabel(app.Cb22, 'Z')
            app.Cb11 = uiaxes(app.UIFigure);
            title(app.Cb11, '4:1:1 (Cb)')
            xlabel(app.Cb11, 'X')
            ylabel(app.Cb11, 'Y')
            zlabel(app.Cb11, 'Z')
            app.Cr44 = uiaxes(app.UIFigure);
            title(app.Cr44, '4:4:4 (Cr)')
            xlabel(app.Cr44, 'X')
            ylabel(app.Cr44, 'Y')
            zlabel(app.Cr44, 'Z')
            app.Cr22 = uiaxes(app.UIFigure);
            title(app.Cr22, '4:2:2 (Cr)')
            xlabel(app.Cr22, 'X')
            ylabel(app.Cr22, 'Y')
            zlabel(app.Cr22, 'Z')
            app.Cr11 = uiaxes(app.UIFigure);
            title(app.Cr11, '4:1:1 (Cr)')
            xlabel(app.Cr11, 'X')
            ylabel(app.Cr11, 'Y')
            zlabel(app.Cr11, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SubsampleButton = uibutton(app.UIFigure, 'push');
            app.SubsampleButton.ButtonPushedFcn = createCallbackFcn(app, @SubsampleButtonPushed, true);
            app.SubsampleButton.Text = 'Subsample';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subsampling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end