classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        status_label                matlab.ui.control.Label
        status_lamp                 matlab.ui.control.Lamp
        CompressButton              matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        QualityScalingFactorSlider  matlab.ui.control.Slider
        QualityScalingFactorSliderLabel  matlab.ui.control.Label
        JPEGCompressionLabel        matlab.ui.control.Label
        compressed_img_container    matlab.ui.control.UIAxes
        orig_img_container          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        orig_img % The original image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Indicate that the image is being loaded
            app.status_label.Text = "Loading image...";
            app.status_lamp.Color = "yellow";

            % Get image filename
            [img_filename, img_path] = uigetfile("*.*", "Select an Image");
            img_path = fullfile(img_path, img_filename);

            % Refocus on the window
            focus(app.CompressButton);

            try
                % Read the image into the app
                app.orig_img = imread(img_path);
                imshow(app.orig_img, 'Parent', app.orig_img_container);

                % Let user know the operation succeeded
                app.status_label.Text = "Image loaded; ready for compression";
                app.status_lamp.Color = "green";
            catch exception
                % Display error message
                fprintf("Could not read image!\n%s: %s\n", ...
                    exception.identifier, ...
                    exception.message);
                app.status_label.Text = "Image could not be loaded; see console for details";
                app.status_lamp.Color = "red";
                return;

            end
        end

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)
            % Indicate that the image is being compressed
            app.status_label.Text = "Compressing image...";
            app.status_lamp.Color = "yellow";
            pause(.01);
            imshow(JPEG_Compression(app.orig_img, 8, app.QualityScalingFactorSlider.Value), 'Parent', app.compressed_img_container);
            app.status_label.Text = "Image successfully compressed";
            app.status_lamp.Color = "green";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.orig_img_container = uiaxes(app.GridLayout);
            title(app.orig_img_container, 'Original Image')
            xlabel(app.orig_img_container, 'X')
            ylabel(app.orig_img_container, 'Y')
            zlabel(app.orig_img_container, 'Z')
            app.compressed_img_container = uiaxes(app.GridLayout);
            title(app.compressed_img_container, 'Compressed Image')
            xlabel(app.compressed_img_container, 'X')
            ylabel(app.compressed_img_container, 'Y')
            zlabel(app.compressed_img_container, 'Z')
            app.JPEGCompressionLabel = uilabel(app.GridLayout);
            app.JPEGCompressionLabel.Text = 'JPEG Compression';
            app.QualityScalingFactorSliderLabel = uilabel(app.GridLayout);
            app.QualityScalingFactorSliderLabel.Text = 'Quality Scaling Factor';
            app.QualityScalingFactorSlider = uislider(app.GridLayout);
            app.QualityScalingFactorSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.LoadImageButton = uibutton(app.GridLayout, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.CompressButton = uibutton(app.GridLayout, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress';
            app.status_lamp = uilamp(app.GridLayout);
            app.status_label = uilabel(app.GridLayout);
            app.status_label.Text = 'Waiting for image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end